classdef FUSESAFE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DGeometryViewPanel              matlab.ui.container.Panel
        GeometryImage                   matlab.ui.control.Image
        OptimizationPlotsPanel          matlab.ui.container.Panel
        StressPlotAxes                  matlab.ui.control.UIAxes
        MassPlotAxes                    matlab.ui.control.UIAxes
        FEAResultsANSYSPanel            matlab.ui.container.Panel
        FEADeformationImage             matlab.ui.control.Image
        FEAStressImage                  matlab.ui.control.Image
        OptimizationHistoryPanel        matlab.ui.container.Panel
        HistoryTable                    matlab.ui.control.Table
        OptimizationResultsPanel        matlab.ui.container.Panel
        ExportReportButton              matlab.ui.control.Button
        DisplacementEditField           matlab.ui.control.NumericEditField
        DisplacementEditFieldLabel      matlab.ui.control.Label
        ActualFoSEditField              matlab.ui.control.NumericEditField
        ActualFosEditFieldLabel         matlab.ui.control.Label
        StressEditField                 matlab.ui.control.NumericEditField
        StressEditFieldLabel            matlab.ui.control.Label
        AddedMassEditField              matlab.ui.control.NumericEditField
        AddedMassEditFieldLabel         matlab.ui.control.Label
        AddedThicknessEditField         matlab.ui.control.NumericEditField
        AddedThicknessEditFieldLabel    matlab.ui.control.Label
        MaterialFixedPanel              matlab.ui.container.Panel
        Label4                          matlab.ui.control.Label
        Densityrho2780kgmLabel          matlab.ui.control.Label
        PoissonsRationu033Label         matlab.ui.control.Label
        YoungsModulusE73100MPaLabel     matlab.ui.control.Label
        AluminiumAlloy2024T3Label       matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        OptimizereinforcementsButton    matlab.ui.control.Button
        FUSESAFEPanel                   matlab.ui.container.Panel
        AircraftfuselagePanel           matlab.ui.container.Panel
        DesignInputsPanel               matlab.ui.container.Panel
        WidthEditField                  matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.Label
        SkinThicknessEditField          matlab.ui.control.Label
        PressureDifferenceEditField     matlab.ui.control.Label
        step10Label                     matlab.ui.control.Label
        RequiredFoSEditField            matlab.ui.control.NumericEditField
        RequiredFoSEditFieldLabel       matlab.ui.control.Label
        PressureDifferencekPaEditField  matlab.ui.control.NumericEditField
        EditField5Label                 matlab.ui.control.Label
        SkinThicknessmmEditField        matlab.ui.control.NumericEditField
        SkinThicknessmmEditFieldLabel   matlab.ui.control.Label
        RadiusmmEditField               matlab.ui.control.NumericEditField
        RadiusmmEditFieldLabel          matlab.ui.control.Label
        HeightmmEditField               matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel          matlab.ui.control.Label
        WidthmmEditField                matlab.ui.control.NumericEditField
        WidthmmEditFieldLabel           matlab.ui.control.Label
    end

    
    methods (Access = private)
    
        function loadGenuineData(app, runID)
            % 1. Build path to run directory
            runDir = fullfile('runs', runID);
            resultsFile = fullfile(runDir, 'results.csv');
            historyFile = fullfile(runDir, 'optimization_history.csv');

            % 2. Read Optimization History CSV and perform dynamic candidate selection
            if exist(historyFile, 'file') == 2
                historyTable = readtable(historyFile);
                app.HistoryTable.Data = historyTable;

                % Detect column names dynamically (handles 'Thickness_mm' vs 'Thickness')
                vars = historyTable.Properties.VariableNames;
                thickCol = vars{contains(vars, {'Thickness', 'thick'}, 'IgnoreCase', true)};
                massCol  = vars{contains(vars, {'Mass', 'mass'}, 'IgnoreCase', true)};
                stressCol= vars{contains(vars, {'Stress', 'stress'}, 'IgnoreCase', true)};

                % Extract arrays
                thicknessArr = historyTable.(thickCol);
                massArr      = historyTable.(massCol);
                stressArr    = historyTable.(stressCol);
                actualFoSArr = 290 ./ stressArr;

                % Plot Panel 5 Graphs
                plot(app.MassPlotAxes, thicknessArr, massArr, '-ob', 'LineWidth', 2);
                grid(app.MassPlotAxes, 'on');
                xlabel(app.MassPlotAxes, 'Added Thickness (mm)');
                ylabel(app.MassPlotAxes, 'Mass (kg)');

                plot(app.StressPlotAxes, thicknessArr, stressArr, '-^r', 'LineWidth', 2);
                grid(app.StressPlotAxes, 'on');
                xlabel(app.StressPlotAxes, 'Added Thickness (mm)');
                ylabel(app.StressPlotAxes, 'Stress (MPa)');

                % Check user's target Required FoS
                reqFoS = app.RequiredFoSEditField.Value;
                validIdx = find(actualFoSArr >= reqFoS, 1, 'first');

                if ~isempty(validIdx)
                    % Update Panel 2 with the dynamic passing candidate
                    selIdx = validIdx;
                else
                    % If no candidate satisfies FoS target, fallback to the heaviest attempted candidate
                    selIdx = height(historyTable);
                    % Inform user of outcome
                    title(app.OptimizationResultsPanel, '2. Optimization Results (No feasible tested candidate)');
                end

                app.AddedThicknessEditField.Value = thicknessArr(selIdx);
                app.AddedMassEditField.Value      = massArr(selIdx);
                app.StressEditField.Value         = stressArr(selIdx);
                app.ActualFoSEditField.Value      = round(actualFoSArr(selIdx), 2);
            end

            % 3. Read Results CSV for Displacement
            if exist(resultsFile, 'file') == 2
                resTable = readtable(resultsFile);
                if ismember('MaxDisplacement', resTable.Properties.VariableNames)
                    app.DisplacementEditField.Value = resTable.MaxDisplacement(1);
                end
            end

            % 4. Load Contour & 3D Images (Panels 3 & 6)
            stressImgPath = fullfile(runDir, 'stress_contour.png');
            defImgPath    = fullfile(runDir, 'deformation_contour.png');
            geomImgPath   = fullfile(runDir, 'geometry_3d.png');

            if exist(stressImgPath, 'file') == 2
                app.FEAStressImage.ImageSource = stressImgPath;
            end
            if exist(defImgPath, 'file') == 2
                app.FEADeformationImage.ImageSource = defImgPath;
            end
            if exist(geomImgPath, 'file') == 2
                app.GeometryImage.ImageSource = geomImgPath;
            end
        end
        
        function clearResults(app)
            % Reset numeric output fields
            app.AddedThicknessEditField.Value = 0;
            app.AddedMassEditField.Value      = 0;
            app.StressEditField.Value         = 0;
            app.ActualFoSEditField.Value      = 0;
            app.DisplacementEditField.Value   = 0;

            % Clear history table data
            app.HistoryTable.Data = [];

            % Clear plot axes
            cla(app.MassPlotAxes);
            cla(app.StressPlotAxes);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                % Reset design input fields to default values
                app.WidthmmEditField.Value = 400;      % Width (mm)
                app.HeightmmEditField.Value = 400;         % Height (mm)
                app.RadiusmmEditField.Value = 2000;       % Radius (mm)
                app.SkinThicknessmmEditField.Value = 2.0;       % Skin Thickness (mm)
                app.PressureDifferencekPaEditField.Value = 55;       % Pressure Difference (kPa)
    
                % Check if you have a 6th input field for Required FoS:
                app.RequiredFoSEditField.Value = 2.0;
    
                % Reset output fields back to zero
                app.AddedThicknessEditField.Value = 0;
                app.AddedMassEditField.Value = 0;
                app.StressEditField.Value = 0;
                app.ActualFoSEditField.Value = 0;
                app.DisplacementEditField.Value = 0;
        end

        % Button pushed function: OptimizereinforcementsButton
        function OptimizereinforcementsButtonPushed(app, event)
            % 1. Disable duplicate launches & show progress
            app.OptimizereinforcementsButton.Enable = 'off';
            app.OptimizereinforcementsButton.Text = 'Searching...';
            drawnow; % Force UI to update text immediately

            try
                % 2. Take a fixed snapshot of all design inputs
                inputs.Width            = app.WidthmmEditField.Value;
                inputs.Height           = app.HeightmmEditField.Value;
                inputs.CornerRadius     = app.RadiusmmEditField.Value;
                inputs.SkinThickness    = app.SkinThicknessmmEditField.Value;
                inputs.InternalPressure = app.PressureDifferencekPaEditField.Value;
                inputs.RequiredFoS      = app.RequiredFoSEditField.Value;
                inputs.YieldStrength    = 290; % Yield strength for Al 2024-T3 (MPa)

                % 3. Execute search workflow (returns status and runID)
                % Note: Replace run_optimization_search with Member 4's exact function name once available
                if exist('run_optimization_search', 'file') == 2
                    [status, runID] = run_optimization_search(inputs);
                else
                    % Mock fallthrough for local testing before Member 4's script arrives
                    status = 'SUCCESS';
                    runID  = 'run_001';
                end

                % 4. Handle search failures or incomplete runs clearly
                if ~ismember(status, {'SUCCESS', 'PASSED', 'SUCCESS_WITH_WARNINGS'})
                    uialert(app.UIFigure, ...
                        ['Optimization search did not complete. Status: ', status], ...
                        'Search Terminated', 'Icon', 'warning');

                    % Re-enable button and exit early
                    app.OptimizereinforcementsButton.Enable = 'on';
                    app.OptimizereinforcementsButton.Text = 'Optimize reinforcements';
                    return;
                end

                % 5. Import and display final matching results and visuals
                loadGenuineData(app, runID);

            catch ME
                % Handle unexpected execution crashes safely
                uialert(app.UIFigure, ['Execution Error: ', ME.message], ...
                    'Workflow Failure', 'Icon', 'error');
            end

            % Always restore button state after search finishes
            app.OptimizereinforcementsButton.Enable = 'on';
            app.OptimizereinforcementsButton.Text = 'Optimize reinforcements';
        end

        % Selection changed function: HistoryTable
        function HistoryTableSelectionChanged(app, event)
    
                
        end

        % Value changed function: WidthmmEditField
        function WidthmmEditFieldValueChanged(app, event)
            app.clearResults();            
        end

        % Value changed function: HeightmmEditField
        function HeightmmEditFieldValueChanged(app, event)
            app.clearResults();
            
        end

        % Value changed function: RadiusmmEditField
        function RadiusmmEditFieldValueChanged(app, event)
            app.clearResults();
            
        end

        % Value changed function: SkinThicknessmmEditField
        function SkinThicknessmmEditFieldValueChanged(app, event)
            app.clearResults();
            
        end

        % Value changed function: PressureDifferencekPaEditField
        function PressureDifferencekPaEditFieldValueChanged(app, event)
            app.clearResults();
            
        end

        % Value changed function: RequiredFoSEditField
        function RequiredFoSEditFieldValueChanged(app, event)
            app.clearResults();
            
        end

        % Button pushed function: ExportReportButton
        function ExportReportButtonPushed(app, event)
            % 1. Open file save dialog
            [file, path] = uiputfile('*.txt', 'Save Optimization Report', 'Optimization_Report.txt');
            if isequal(file, 0) || isequal(path, 0)
                return; % User canceled
            end

            filePath = fullfile(path, file);
            fid = fopen(filePath, 'w');
            if fid == -1
                uialert(app.UIFigure, 'Could not create report file.', 'Export Error', 'Icon', 'error');
                return;
            end

            % 2. Write structured report data
            fprintf(fid, '==================================================\n');
            fprintf(fid, '           FUSESAFE OPTIMIZATION REPORT           \n');
            fprintf(fid, '==================================================\n\n');

            fprintf(fid, 'WORKFLOW MODE: Integrated ANSYS Optimization\n');
            fprintf(fid, 'TIMESTAMP:     %s\n\n', string(datetime('now')));

            fprintf(fid, '--- 1. DESIGN INPUTS ---\n');
            fprintf(fid, 'Width:               %.2f mm\n', app.WidthmmEditField.Value);
            fprintf(fid, 'Height:              %.2f mm\n', app.HeightmmEditField.Value);
            fprintf(fid, 'Corner Radius:       %.2f mm\n', app.RadiusmmEditField.Value);
            fprintf(fid, 'Skin Thickness:      %.2f mm\n', app.SkinThicknessmmEditField.Value);
            fprintf(fid, 'Pressure Diff:       %.2f kPa\n', app.PressureDifferencekPaEditField.Value);
            fprintf(fid, 'Required FoS:        %.2f\n\n', app.RequiredFoSEditField.Value);

            fprintf(fid, '--- 2. MATERIAL PROPERTIES (Fixed) ---\n');
            fprintf(fid, 'Material:            Aluminium Alloy 2024-T3\n');
            fprintf(fid, 'Young Modulus (E):   73,100 MPa\n');
            fprintf(fid, 'Poisson Ratio (nu):  0.33\n');
            fprintf(fid, 'Density (rho):       2,780 kg/m^3\n');
            fprintf(fid, 'Yield Strength:      290 MPa\n\n');
            fprintf(fid, '--- OUTCOME STATUS ---\n');

            if app.ActualFoSEditField.Value < app.RequiredFoSEditField.Value
                fprintf(fid, 'Status:              No feasible tested candidate\n\n');
            else
                fprintf(fid, 'Status:              Feasible Candidate Found\n\n');
            end

            fprintf(fid, '--- 3. OPTIMIZATION RESULTS ---\n');
            fprintf(fid, 'Added Thickness:     %.2f mm\n', app.AddedThicknessEditField.Value);
            fprintf(fid, 'Added Mass:          %.2f kg\n', app.AddedMassEditField.Value);
            fprintf(fid, 'Max Stress:          %.2f MPa\n', app.StressEditField.Value);
            fprintf(fid, 'Actual FoS:          %.2f\n', app.ActualFoSEditField.Value);
            fprintf(fid, 'Max Displacement:    %.2f mm\n\n', app.DisplacementEditField.Value);

            fprintf(fid, '==================================================\n');
            fclose(fid);

            % 3. Show confirmation alert
            uialert(app.UIFigure, ['Report saved successfully to:\n', filePath], ...
                'Export Complete', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DesignInputsPanel = uipanel(app.UIFigure);
            app.DesignInputsPanel.Title = '1. Design Inputs';
            app.WidthmmEditFieldLabel = uilabel(app.DesignInputsPanel);
            app.WidthmmEditFieldLabel.Text = 'Width (mm)';
            app.WidthmmEditField = uieditfield(app.DesignInputsPanel, 'numeric');
            app.WidthmmEditField.Limits = [250 500];
            app.WidthmmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthmmEditFieldValueChanged, true);
            app.WidthmmEditField.Tooltip = {'Axial cutout width (mm)'};
            app.WidthmmEditField.Value = 400;
            app.HeightmmEditFieldLabel = uilabel(app.DesignInputsPanel);
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.HeightmmEditField = uieditfield(app.DesignInputsPanel, 'numeric');
            app.HeightmmEditField.Limits = [300 550];
            app.HeightmmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmmEditFieldValueChanged, true);
            app.HeightmmEditField.Tooltip = {'Circumferential arc length (mm)'};
            app.HeightmmEditField.Value = 400;
            app.RadiusmmEditFieldLabel = uilabel(app.DesignInputsPanel);
            app.RadiusmmEditFieldLabel.Text = ' Radius (mm) ';
            app.RadiusmmEditField = uieditfield(app.DesignInputsPanel, 'numeric');
            app.RadiusmmEditField.Limits = [1500 2500];
            app.RadiusmmEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusmmEditFieldValueChanged, true);
            app.RadiusmmEditField.Tooltip = {'Fuselage midsurface radius (mm)'};
            app.RadiusmmEditField.Value = 2000;
            app.SkinThicknessmmEditFieldLabel = uilabel(app.DesignInputsPanel);
            app.SkinThicknessmmEditFieldLabel.Text = 'Skin Thickness (mm)';
            app.SkinThicknessmmEditField = uieditfield(app.DesignInputsPanel, 'numeric');
            app.SkinThicknessmmEditField.Limits = [1.8 3];
            app.SkinThicknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @SkinThicknessmmEditFieldValueChanged, true);
            app.SkinThicknessmmEditField.Value = 2;
            app.EditField5Label = uilabel(app.DesignInputsPanel);
            app.EditField5Label.Text = 'Pressure Difference (kPa)';
            app.PressureDifferencekPaEditField = uieditfield(app.DesignInputsPanel, 'numeric');
            app.PressureDifferencekPaEditField.Limits = [40 70];
            app.PressureDifferencekPaEditField.ValueChangedFcn = createCallbackFcn(app, @PressureDifferencekPaEditFieldValueChanged, true);
            app.PressureDifferencekPaEditField.Value = 55;
            app.RequiredFoSEditFieldLabel = uilabel(app.DesignInputsPanel);
            app.RequiredFoSEditFieldLabel.Text = 'Required FoS';
            app.RequiredFoSEditField = uieditfield(app.DesignInputsPanel, 'numeric');
            app.RequiredFoSEditField.Limits = [1.5 2.5];
            app.RequiredFoSEditField.ValueChangedFcn = createCallbackFcn(app, @RequiredFoSEditFieldValueChanged, true);
            app.RequiredFoSEditField.Value = 1.5;
            app.step10Label = uilabel(app.DesignInputsPanel);
            app.step10Label.Text = '250-500, step 10';
            app.PressureDifferenceEditField = uilabel(app.DesignInputsPanel);
            app.PressureDifferenceEditField.Text = '300–550, step 10';
            app.SkinThicknessEditField = uilabel(app.DesignInputsPanel);
            app.SkinThicknessEditField.Text = '1500–2500, step 100';
            app.RadiusEditField = uilabel(app.DesignInputsPanel);
            app.RadiusEditField.Text = '1.8–3.0, step 0.1';
            app.HeightEditField = uilabel(app.DesignInputsPanel);
            app.HeightEditField.Text = '40–70, step 5';
            app.WidthEditField = uilabel(app.DesignInputsPanel);
            app.WidthEditField.Text = '1.5–2.5, step 0.1';
            app.FUSESAFEPanel = uipanel(app.UIFigure);
            app.FUSESAFEPanel.Title = 'FUSESAFE';
            app.AircraftfuselagePanel = uipanel(app.FUSESAFEPanel);
            app.AircraftfuselagePanel.Title = 'Aircraft fuselage window reinforcement optimization';
            app.MaterialFixedPanel = uipanel(app.UIFigure);
            app.MaterialFixedPanel.Title = 'Material (Fixed)';
            app.OptimizereinforcementsButton = uibutton(app.MaterialFixedPanel, 'push');
            app.OptimizereinforcementsButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizereinforcementsButtonPushed, true);
            app.OptimizereinforcementsButton.Text = 'Optimize reinforcements';
            app.ResetButton = uibutton(app.MaterialFixedPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset ';
            app.AluminiumAlloy2024T3Label = uilabel(app.MaterialFixedPanel);
            app.AluminiumAlloy2024T3Label.Text = 'Aluminium Alloy 2024-T3';
            app.YoungsModulusE73100MPaLabel = uilabel(app.MaterialFixedPanel);
            app.YoungsModulusE73100MPaLabel.Text = 'Young’s Modulus (E): 73,100 MPa';
            app.PoissonsRationu033Label = uilabel(app.MaterialFixedPanel);
            app.PoissonsRationu033Label.Text = 'Poisson’s Ratio (nu): 0.33';
            app.Densityrho2780kgmLabel = uilabel(app.MaterialFixedPanel);
            app.Densityrho2780kgmLabel.Text = 'Density (rho): 2,780 kg/m³';
            app.Label4 = uilabel(app.MaterialFixedPanel);
            app.Label4.Text = 'Tensile Yield Strength (sigma_y): 290 MPa';
            app.OptimizationResultsPanel = uipanel(app.UIFigure);
            app.OptimizationResultsPanel.Title = '2. Optimization Results';
            app.AddedThicknessEditFieldLabel = uilabel(app.OptimizationResultsPanel);
            app.AddedThicknessEditFieldLabel.Text = 'Added Thickness';
            app.AddedThicknessEditField = uieditfield(app.OptimizationResultsPanel, 'numeric');
            app.AddedThicknessEditField.Editable = 'off';
            app.AddedMassEditFieldLabel = uilabel(app.OptimizationResultsPanel);
            app.AddedMassEditFieldLabel.Text = 'Added Mass';
            app.AddedMassEditField = uieditfield(app.OptimizationResultsPanel, 'numeric');
            app.AddedMassEditField.Editable = 'off';
            app.StressEditFieldLabel = uilabel(app.OptimizationResultsPanel);
            app.StressEditFieldLabel.Text = 'Stress';
            app.StressEditField = uieditfield(app.OptimizationResultsPanel, 'numeric');
            app.StressEditField.Editable = 'off';
            app.ActualFosEditFieldLabel = uilabel(app.OptimizationResultsPanel);
            app.ActualFosEditFieldLabel.Text = 'Actual Fos';
            app.ActualFoSEditField = uieditfield(app.OptimizationResultsPanel, 'numeric');
            app.ActualFoSEditField.Editable = 'off';
            app.DisplacementEditFieldLabel = uilabel(app.OptimizationResultsPanel);
            app.DisplacementEditFieldLabel.Text = 'Displacement';
            app.DisplacementEditField = uieditfield(app.OptimizationResultsPanel, 'numeric');
            app.DisplacementEditField.Editable = 'off';
            app.ExportReportButton = uibutton(app.OptimizationResultsPanel, 'push');
            app.ExportReportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportReportButtonPushed, true);
            app.ExportReportButton.Text = 'Export Report';
            app.OptimizationHistoryPanel = uipanel(app.UIFigure);
            app.OptimizationHistoryPanel.Title = '4. Optimization History';
            app.HistoryTable = uitable(app.OptimizationHistoryPanel);
            app.HistoryTable.ColumnName = {'Iter'; 'Thickness (mm)'; 'Mass (kg)'; 'Stress(MPa)'; 'FoS'};
            app.HistoryTable.RowName = {};
            app.HistoryTable.SelectionChangedFcn = createCallbackFcn(app, @HistoryTableSelectionChanged, true);
            app.FEAResultsANSYSPanel = uipanel(app.UIFigure);
            app.FEAResultsANSYSPanel.Title = '3. FEA Results (ANSYS)';
            app.FEAStressImage = uiimage(app.FEAResultsANSYSPanel);
            app.FEADeformationImage = uiimage(app.FEAResultsANSYSPanel);
            app.OptimizationPlotsPanel = uipanel(app.UIFigure);
            app.OptimizationPlotsPanel.Title = '5. Optimization Plots';
            app.MassPlotAxes = uiaxes(app.OptimizationPlotsPanel);
            title(app.MassPlotAxes, 'Mass Plot Axes')
            xlabel(app.MassPlotAxes, 'Added Thickness (mm)')
            ylabel(app.MassPlotAxes, 'Mass (kg')
            zlabel(app.MassPlotAxes, 'Z')
            app.StressPlotAxes = uiaxes(app.OptimizationPlotsPanel);
            title(app.StressPlotAxes, 'Stress Plot Axes')
            xlabel(app.StressPlotAxes, 'Added Thickness (mm)')
            ylabel(app.StressPlotAxes, 'Stress (MPa)')
            zlabel(app.StressPlotAxes, 'Z')
            app.DGeometryViewPanel = uipanel(app.UIFigure);
            app.DGeometryViewPanel.Title = '6. 3D Geometry View';
            app.GeometryImage = uiimage(app.DGeometryViewPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FUSESAFE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end